classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        txtpunitario                  matlab.ui.control.NumericEditField
        PreciounitarioEditFieldLabel  matlab.ui.control.Label
        txtcantidad                   matlab.ui.control.NumericEditField
        CantidadEditFieldLabel        matlab.ui.control.Label
        txtpcosto                     matlab.ui.control.NumericEditField
        PreciocostoEditFieldLabel     matlab.ui.control.Label
        AnularButton                  matlab.ui.control.Button
        EliminarButton                matlab.ui.control.StateButton
        ModificarButton               matlab.ui.control.StateButton
        InsertarButton                matlab.ui.control.StateButton
        tabla                         matlab.ui.control.Table
        MenuLabel                     matlab.ui.control.Label
    end

    
    properties (Access = private)
        idDato
    end
    methods (Access = private)
    function funcionLimpiar(app)
        app.tabla.Data         = consultaDato;
        app.txtcantidad.Value  = 0;
        app.txtpcosto.Value    = 0;
        app.txtpunitario.Value = 0;
    end
end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.tabla.Data = consultaDato
        end

        % Button pushed function: AnularButton
        function AnularButtonPushed(app, event)

    anularDato(app.idDato)
            funcionLimpiar(app)
            msgbox("Registro Anulado!")

        end

        % Value changed function: InsertarButton
        function InsertarButtonValueChanged(app, event)
            
            cantidad       = app.txtcantidad.Value;
    precioCosto    = app.txtpcosto.Value;
    precioUnitario = app.txtpunitario.Value;
    insertarDato(cantidad, precioCosto, precioUnitario);
    app.funcionLimpiar();
    msgbox('Registro Ingresado!');

        end

        % Value changed function: ModificarButton
        function ModificarButtonValueChanged(app, event)
            
            cantidad       = app.txtcantidad.Value;
    precioCosto    = app.txtpcosto.Value;
    precioUnitario = app.txtpunitario.Value;
    modificarDato(app.idDato, cantidad, precioCosto, precioUnitario);
    msgbox('Registro Modificado');
    app.funcionLimpiar();

        end

        % Value changed function: EliminarButton
        function EliminarButtonValueChanged(app, event)
            
                eliminarDato(app.idDato);
    msgbox('Registro Eliminado');
    app.funcionLimpiar();

        end

        % Selection changed function: tabla
        function tablaSelectionChanged(app, event)
           
            
    selection = app.tabla.Selection;
            if ~isempty(selection)
                selectRow = selection(1);
                selecId = app.tabla.Data{selectRow, 1};
                can = app.tabla.Data{selectRow, 2};
                precCos = app.tabla.Data{selectRow, 3};
                precUn = app.tabla.Data{selectRow, 4};
                app.txtcantidad.Value = can;
                app.txtpcosto.Value = precCos;
                app.txtpunitario.Value = precUn;
                app.idDato = selecId;
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MenuLabel = uilabel(app.UIFigure);
            app.MenuLabel.Text = 'Menu';
            app.tabla = uitable(app.UIFigure);
            app.tabla.ColumnName = {'ID'; 'Cantidad'; 'Precio-Costo'; 'Precio-Unitario'};
            app.tabla.RowName = {};
            app.tabla.SelectionChangedFcn = createCallbackFcn(app, @tablaSelectionChanged, true);
            app.InsertarButton = uibutton(app.UIFigure, 'state');
            app.InsertarButton.ValueChangedFcn = createCallbackFcn(app, @InsertarButtonValueChanged, true);
            app.InsertarButton.Text = 'Insertar';
            app.ModificarButton = uibutton(app.UIFigure, 'state');
            app.ModificarButton.ValueChangedFcn = createCallbackFcn(app, @ModificarButtonValueChanged, true);
            app.ModificarButton.Text = 'Modificar';
            app.EliminarButton = uibutton(app.UIFigure, 'state');
            app.EliminarButton.ValueChangedFcn = createCallbackFcn(app, @EliminarButtonValueChanged, true);
            app.EliminarButton.Text = 'Eliminar';
            app.AnularButton = uibutton(app.UIFigure, 'push');
            app.AnularButton.ButtonPushedFcn = createCallbackFcn(app, @AnularButtonPushed, true);
            app.AnularButton.Text = 'Anular';
            app.PreciocostoEditFieldLabel = uilabel(app.UIFigure);
            app.PreciocostoEditFieldLabel.Text = 'Precio costo';
            app.txtpcosto = uieditfield(app.UIFigure, 'numeric');
            app.CantidadEditFieldLabel = uilabel(app.UIFigure);
            app.CantidadEditFieldLabel.Text = 'Cantidad';
            app.txtcantidad = uieditfield(app.UIFigure, 'numeric');
            app.PreciounitarioEditFieldLabel = uilabel(app.UIFigure);
            app.PreciounitarioEditFieldLabel.Text = 'Precio unitario';
            app.txtpunitario = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end